classdef Applol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        OpenasignalButton              matlab.ui.control.Button
        BPMGaugeLabel                  matlab.ui.control.Label
        BPMGauge                       matlab.ui.control.Gauge
        ChecktheBPMButton              matlab.ui.control.Button
        BradychardiaLabel              matlab.ui.control.Label
        BPM100Label                    matlab.ui.control.Label
        CenteraQRScomplexnumberLabel   matlab.ui.control.Label
        beatnumber                     matlab.ui.control.NumericEditField
        DisplayitButton                matlab.ui.control.Button
        DisplaythisectopicbeatsButton  matlab.ui.control.Button
        ResetViewButton                matlab.ui.control.Button
        TachychardiaLabel              matlab.ui.control.Label
        riskLabel                      matlab.ui.control.Label
        riskLabel_2                    matlab.ui.control.Label
        BPM60Label                     matlab.ui.control.Label
        CardiacDetectionAppLabel       matlab.ui.control.Label
        BPMValueEditFieldLabel         matlab.ui.control.Label
        BPMValue                       matlab.ui.control.NumericEditField
        OnatotalofectopicsLabel        matlab.ui.control.Label
        Numberecto                     matlab.ui.control.NumericEditField
        ChooseaectobeatnumberLabel     matlab.ui.control.Label
        Chooseecto                     matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        signal,
        data,
        Fs,
        N,
        time_axis,
        R_loc,
        S_loc,
        Q_loc,
        bpm,
        cible,
        indicesecto,
        ecto_loc,
        window,
        t_axis,
        next,
        ectochoice,
        th,
    end


    methods (Access = private)

        % Button pushed function: OpenasignalButton
        function OpenasignalButtonPushed(app, event)
           [file,path] = uigetfile('*.mat', 'rt');
           app.signal = load(fullfile(path, file));
           app.data = app.signal.ecg;
           app.Fs = app.signal.Fs;
           app.N = size(app.data,2);
           app.time_axis = (1:app.N)/app.Fs;
           plot(app.UIAxes,app.time_axis, app.data); %grid on;
           xlabel(app.UIAxes,'Time (s)');
           ylabel(app.UIAxes,'Magnitude');
           textLabel = sprintf('Time evolution of the loaded signal'); % Description
           [app.R_loc, app.Q_loc, app.S_loc, app.th ] = QRSDetection(app.data,app.Fs);
           [app.indicesecto] = ectopicBeats(app.R_loc,app.Fs);
           app.Numberecto.Value = length(app.indicesecto);
        end

        % Button pushed function: ChecktheBPMButton
        function ChecktheBPMButtonPushed(app, event)
            [app.bpm] = TachycardiaOrBradycardia( app.R_loc, app.Fs)
            app.BPMGauge.Value = app.bpm;
            app.BPMValue.Value = app.bpm;
            %sprintf('app.bpm','%d');
        end

        % Value changed function: beatnumber
        function beatnumberValueChanged(app, event)
            app.cible = app.beatnumber.Value
            
        end

        % Button pushed function: DisplayitButton
        function DisplayitButtonPushed(app, event)
            hold(app.UIAxes,'on');
              
            %              [ app.t_axis ] = plotQRS(app.data, app.Fs, app.Q_loc(app.cible), app.R_loc(app.cible), app.S_loc(app.cible), (app.cible)-1,(app.cible)+1, app.cible)
            %              plot(app.UIAxes, app.t_axis, app.data(app.t_axis));
            %              
             
             
            plot(app.UIAxes,floor(app.R_loc(app.cible))/app.Fs,app.data(app.R_loc(app.cible)),'*','color','red');
            text(app.UIAxes,floor(app.R_loc(app.cible))/app.Fs,app.data(app.R_loc(app.cible)), ' R', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.R_loc(app.cible-1))/app.Fs,app.data(app.R_loc(app.cible-1)),'*','color','red');
            text(app.UIAxes,floor(app.R_loc(app.cible-1))/app.Fs,app.data(app.R_loc(app.cible-1)), ' R', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.R_loc(app.cible+1))/app.Fs,app.data(app.R_loc(app.cible+1)),'*','color','red');
            text(app.UIAxes,floor(app.R_loc(app.cible+1))/app.Fs,app.data(app.R_loc(app.cible+1)), ' R', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.Q_loc(app.cible))/app.Fs,app.data(app.Q_loc(app.cible)),'*','color','red');
            text(app.UIAxes,floor(app.Q_loc(app.cible))/app.Fs,app.data(app.Q_loc(app.cible)), ' Q', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.Q_loc(app.cible-1))/app.Fs,app.data(app.Q_loc(app.cible-1)),'*','color','red');
            text(app.UIAxes,floor(app.Q_loc(app.cible-1))/app.Fs,app.data(app.Q_loc(app.cible-1)), ' Q', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.Q_loc(app.cible+1))/app.Fs,app.data(app.Q_loc(app.cible+1)),'*','color','red');
            text(app.UIAxes,floor(app.Q_loc(app.cible+1))/app.Fs,app.data(app.Q_loc(app.cible+1)), ' Q', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.S_loc(app.cible))/app.Fs,app.data(app.S_loc(app.cible)),'*','color','red');
            text(app.UIAxes,floor(app.S_loc(app.cible))/app.Fs,app.data(app.S_loc(app.cible)), ' S', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.S_loc(app.cible-1))/app.Fs,app.data(app.S_loc(app.cible-1)),'*','color','red');
            text(app.UIAxes,floor(app.S_loc(app.cible-1))/app.Fs,app.data(app.S_loc(app.cible-1)), ' S', 'color', 'red', 'Fontsize', 14);
                        
            plot(app.UIAxes,floor(app.S_loc(app.cible+1))/app.Fs,app.data(app.S_loc(app.cible+1)),'*','color','red');
            text(app.UIAxes,floor(app.S_loc(app.cible+1))/app.Fs,app.data(app.S_loc(app.cible+1)), ' S', 'color', 'red', 'Fontsize', 14);
            
            hold(app.UIAxes,'on');
             
            plot(app.UIAxes,app.Q_loc(app.cible),app.data(app.Q_loc(app.cible)),'x','color','red');
            text(app.UIAxes,app.Q_loc(app.cible),app.data(app.Q_loc(app.cible)), ' Q', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,app.S_loc(app.cible),app.data(app.S_loc(app.cible)),'x','color','red');
            text(app.UIAxes,app.S_loc(app.cible),app.data(app.S_loc(app.cible)), ' S', 'color', 'red', 'Fontsize', 14);
            app.UIAxes.XLim = [(app.Q_loc(app.cible-1)-35)/app.Fs (app.S_loc(app.cible+1)+35)/app.Fs];
            %app.UIAxes.YLim = [-300 500];
            %              app.UIAxes.YLim = [0 1];
            hold(app.UIAxes,'off');
        end

        % Button pushed function: DisplaythisectopicbeatsButton
        function DisplaythisectopicbeatsButtonPushed(app, event)
            %[app.indicesecto] = ectopicBeats(app.R_loc,app.Fs);
            
            %app.Numberecto.Value = length(app.indicesecto);
            
            hold(app.UIAxes,'on');
            
            %QRS ectopics
            plot(app.UIAxes,floor(app.R_loc(app.indicesecto))/app.Fs,app.data(app.R_loc(app.indicesecto)),'*','color','green');
            text(app.UIAxes,floor(app.R_loc(app.indicesecto))/app.Fs,app.data(app.R_loc(app.indicesecto)), ' R', 'color', 'green', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.S_loc(app.indicesecto))/app.Fs,app.data(app.S_loc(app.indicesecto)),'*','color','green');
            text(app.UIAxes,floor(app.S_loc(app.indicesecto))/app.Fs,app.data(app.S_loc(app.indicesecto)), ' S', 'color', 'green', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.Q_loc(app.indicesecto))/app.Fs,app.data(app.Q_loc(app.indicesecto)),'*','color','green');
            text(app.UIAxes,floor(app.Q_loc(app.indicesecto))/app.Fs,app.data(app.Q_loc(app.indicesecto)), ' Q', 'color', 'green', 'Fontsize', 14);
            
            %QRS avant après
            
            plot(app.UIAxes,floor(app.R_loc((app.indicesecto(app.ectochoice))-1))/app.Fs,app.data(app.R_loc((app.indicesecto(app.ectochoice))-1)),'*','color','red');
            text(app.UIAxes,floor(app.R_loc((app.indicesecto(app.ectochoice))-1))/app.Fs,app.data(app.R_loc((app.indicesecto(app.ectochoice))-1)), ' R', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.R_loc((app.indicesecto(app.ectochoice))+1))/app.Fs,app.data(app.R_loc((app.indicesecto(app.ectochoice))+1)),'*','color','red');
            text(app.UIAxes,floor(app.R_loc((app.indicesecto(app.ectochoice))+1))/app.Fs,app.data(app.R_loc((app.indicesecto(app.ectochoice))+1)), ' R', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.Q_loc((app.indicesecto(app.ectochoice))-1))/app.Fs,app.data(app.Q_loc((app.indicesecto(app.ectochoice))-1)),'*','color','red');
            text(app.UIAxes,floor(app.Q_loc((app.indicesecto(app.ectochoice))-1))/app.Fs,app.data(app.Q_loc((app.indicesecto(app.ectochoice))-1)), ' Q', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.Q_loc((app.indicesecto(app.ectochoice))+1))/app.Fs,app.data(app.Q_loc((app.indicesecto(app.ectochoice))+1)),'*','color','red');
            text(app.UIAxes,floor(app.Q_loc((app.indicesecto(app.ectochoice))+1))/app.Fs,app.data(app.Q_loc((app.indicesecto(app.ectochoice))+1)), ' Q', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.S_loc((app.indicesecto(app.ectochoice))-1))/app.Fs,app.data(app.S_loc((app.indicesecto(app.ectochoice))-1)),'*','color','red');
            text(app.UIAxes,floor(app.S_loc((app.indicesecto(app.ectochoice))-1))/app.Fs,app.data(app.S_loc((app.indicesecto(app.ectochoice))-1)), ' S', 'color', 'red', 'Fontsize', 14);
            
            plot(app.UIAxes,floor(app.S_loc((app.indicesecto(app.ectochoice))+1))/app.Fs,app.data(app.S_loc((app.indicesecto(app.ectochoice))+1)),'*','color','red');
            text(app.UIAxes,floor(app.S_loc((app.indicesecto(app.ectochoice))+1))/app.Fs,app.data(app.S_loc((app.indicesecto(app.ectochoice))+1)), ' S', 'color', 'red', 'Fontsize', 14);


            %plot(app.UIAxes,app.indicesecto/app.Fs,app.data(app.indicesecto),'x','color','green');
            %text(app.UIAxes,app.indicesecto,app.data(app.indicesecto), ' Ectopic', 'color', 'black', 'Fontsize', 14);
            app.UIAxes.XLim = [(app.Q_loc((app.indicesecto(app.ectochoice))-1)-35)/app.Fs (app.S_loc((app.indicesecto(app.ectochoice))+1)+35)/app.Fs];
            app.next = 1;
            hold(app.UIAxes,'off');
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            
            plot(app.UIAxes,app.time_axis, app.data);
            app.UIAxes.XLim = [0 app.N/app.Fs];
            app.UIAxes.YLim = [-400 600];
        end

        % Value changed function: BPMValue
        function BPMValueValueChanged(app, event)
            %app.next = app.next + 1;
            app.UIAxes.XLim = [(app.Q_loc((app.indicesecto(app.next+1))-1)-35)/app.Fs (app.S_loc((app.indicesecto(app.next+1))+1)+35)/app.Fs];
            
        end

        % Value changed function: Numberecto
        function NumberectoValueChanged(app, event)
            %app.Numberecto.Value = length(app.indicesecto);
            
        end

        % Callback function
        function TotalectoValueChanged(app, event)
            app.Totalecto.Value = length(app.indicesecto);
            
        end

        % Value changed function: Chooseecto
        function ChooseectoValueChanged(app, event)
            app.ectochoice = app.Chooseecto.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal analysis');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.OpenasignalButton = uibutton(app.UIFigure, 'push');
            app.OpenasignalButton.ButtonPushedFcn = createCallbackFcn(app, @OpenasignalButtonPushed, true);
            app.OpenasignalButton.Text = 'Open a signal';
            app.BPMGaugeLabel = uilabel(app.UIFigure);
            app.BPMGaugeLabel.Text = 'BPM';
            app.BPMGauge = uigauge(app.UIFigure, 'circular');
            app.BPMGauge.Limits = [0 200];
            app.BPMGauge.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200];
            app.ChecktheBPMButton = uibutton(app.UIFigure, 'push');
            app.ChecktheBPMButton.ButtonPushedFcn = createCallbackFcn(app, @ChecktheBPMButtonPushed, true);
            app.ChecktheBPMButton.Text = 'Check the BPM';
            app.BradychardiaLabel = uilabel(app.UIFigure);
            app.BradychardiaLabel.Text = 'Bradychardia';
            app.BPM100Label = uilabel(app.UIFigure);
            app.BPM100Label.Text = 'BPM >100 ';
            app.CenteraQRScomplexnumberLabel = uilabel(app.UIFigure);
            app.CenteraQRScomplexnumberLabel.Text = 'Center a QRS complex number';
            app.beatnumber = uieditfield(app.UIFigure, 'numeric');
            app.beatnumber.ValueChangedFcn = createCallbackFcn(app, @beatnumberValueChanged, true);
            app.DisplayitButton = uibutton(app.UIFigure, 'push');
            app.DisplayitButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayitButtonPushed, true);
            app.DisplayitButton.Text = 'Display it !';
            app.DisplaythisectopicbeatsButton = uibutton(app.UIFigure, 'push');
            app.DisplaythisectopicbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaythisectopicbeatsButtonPushed, true);
            app.DisplaythisectopicbeatsButton.Text = 'Display this ectopic beats';
            app.ResetViewButton = uibutton(app.UIFigure, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.TachychardiaLabel = uilabel(app.UIFigure);
            app.TachychardiaLabel.Text = 'Tachychardia';
            app.riskLabel = uilabel(app.UIFigure);
            app.riskLabel.Text = 'risk';
            app.riskLabel_2 = uilabel(app.UIFigure);
            app.riskLabel_2.Text = 'risk';
            app.BPM60Label = uilabel(app.UIFigure);
            app.BPM60Label.Text = 'BPM < 60 ';
            app.CardiacDetectionAppLabel = uilabel(app.UIFigure);
            app.CardiacDetectionAppLabel.Text = 'Cardiac Detection App';
            app.BPMValueEditFieldLabel = uilabel(app.UIFigure);
            app.BPMValueEditFieldLabel.Text = 'BPM Value';
            app.BPMValue = uieditfield(app.UIFigure, 'numeric');
            app.BPMValue.ValueChangedFcn = createCallbackFcn(app, @BPMValueValueChanged, true);
            app.OnatotalofectopicsLabel = uilabel(app.UIFigure);
            app.OnatotalofectopicsLabel.Text = 'On a total of           ectopics';
            app.Numberecto = uieditfield(app.UIFigure, 'numeric');
            app.Numberecto.ValueChangedFcn = createCallbackFcn(app, @NumberectoValueChanged, true);
            app.ChooseaectobeatnumberLabel = uilabel(app.UIFigure);
            app.ChooseaectobeatnumberLabel.Text = 'Choose a ectobeat number';
            app.Chooseecto = uieditfield(app.UIFigure, 'numeric');
            app.Chooseecto.ValueChangedFcn = createCallbackFcn(app, @ChooseectoValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Applol()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end